classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        DetenercomunicacinButton       matlab.ui.control.Button
        IniciarcomunicacinButton       matlab.ui.control.Button
        PuertoserieEditField           matlab.ui.control.EditField
        PuertoserieEditFieldLabel      matlab.ui.control.Label
        BaudrateEditField              matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel         matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        PinzaSwitch                    matlab.ui.control.Switch
        PinzaSwitchLabel               matlab.ui.control.Label
        PosicinenmmEditField           matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel      matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        PosicinengradosEditField_2     matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        PosicinengradosEditField       matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        BrazoextremoGauge              matlab.ui.control.Gauge
        BrazoextremoGaugeLabel         matlab.ui.control.Label
        BrazointeriorGauge             matlab.ui.control.Gauge
        BrazointeriorGaugeLabel        matlab.ui.control.Label
        AltitudSlider                  matlab.ui.control.Slider
        AltitudSliderLabel             matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comunicacion % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           app.Lamp.Color = [0.80,0.80,0.80];
            
        end

        % Button pushed function: IniciarcomunicacinButton
        function IniciarcomunicacinButtonPushed(app, event)
              port = app.PuertoserieEditField.Value;
            baudrate = app.BaudrateEditField.Value;
            app.comunicacion = serial(port,'BaudRate',baudrate,'Terminator','CR/LF');app.Lamp.Color = 'g';
             fopen(app.comunicacion);
        end

        % Button pushed function: DetenercomunicacinButton
        function DetenercomunicacinButtonPushed(app, event)
            app.Lamp.Color = [0.80,0.80,0.80];
            fclose(app.comunicacion);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.BrazoextremoGauge.Value = app.BrazoextremoGauge.Value+app.PosicinengradosEditField.Value;
            app.EditField.Value = app.BrazoextremoGauge.Value;  
            
            global servomotor1
               value = round(app.BrazoextremoGauge.Value);
               servomotor1 = value;
               
               fwrite(app.comunicacion, 'a');
               fwrite(app.comunicacion,servomotor1,'uint8');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.PosicinengradosEditField_2.Value;
            app.BrazointeriorGauge.Value = app.BrazointeriorGauge.Value+value;
            app.EditField_2.Value = app.BrazointeriorGauge.Value;
            
             
            global servomotor2
               value = round(app.BrazointeriorGauge.Value);
               servomotor2 = value;
               
               fwrite(app.comunicacion, 'a');
               fwrite(app.comunicacion,servomotor2,'uint8');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.AltitudSlider.Value = app.AltitudSlider.Value + app.PosicinenmmEditField.Value;
            app.EditField_3.Value =  app.AltitudSlider.Value;
            
              
            global DCmotor
               value = round(app.AltitudSlider.Value); %tenemos que cambiar los mm a grados!!!
               DCmotor = value;
               
               fwrite(app.comunicacion, 'a');
               fwrite(app.comunicacion,DCmotor,'uint8');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.comunicacion);
            delete(app.comunicacion);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazointeriorGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorGaugeLabel.Text = 'Brazo interior';
            app.BrazointeriorGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorGauge.Limits = [-120 120];
            app.BrazoextremoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoGaugeLabel.Text = 'Brazo extremo';
            app.BrazoextremoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoGauge.Limits = [-120 120];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicinengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField.Value = 15;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicinengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicinenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PinzaSwitch.Items = {'Abierta', 'Cerrada'};
            app.PinzaSwitch.Value = 'Abierta';
            app.Lamp = uilamp(app.UIFigure);
            app.BaudrateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudrateEditFieldLabel.Text = 'Baud rate';
            app.BaudrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuertoserieEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoserieEditFieldLabel.Text = 'Puerto serie';
            app.PuertoserieEditField = uieditfield(app.UIFigure, 'text');
            app.IniciarcomunicacinButton = uibutton(app.UIFigure, 'push');
            app.IniciarcomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcomunicacinButtonPushed, true);
            app.IniciarcomunicacinButton.Text = 'Iniciar comunicación';
            app.DetenercomunicacinButton = uibutton(app.UIFigure, 'push');
            app.DetenercomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercomunicacinButtonPushed, true);
            app.DetenercomunicacinButton.Text = 'Detener comunicación';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end