classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        DetenercomunicacinButton       matlab.ui.control.Button
        IniciarcomunicacinButton       matlab.ui.control.Button
        PuertoserieEditField           matlab.ui.control.EditField
        PuertoserieEditFieldLabel      matlab.ui.control.Label
        BaudrateEditField              matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel         matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        PinzaSwitch                    matlab.ui.control.Switch
        PinzaSwitchLabel               matlab.ui.control.Label
        PosicinenmmEditField           matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel      matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        PosicinengradosEditField_2     matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        PosicinengradosEditField       matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        BrazoextremoGauge              matlab.ui.control.Gauge
        BrazoextremoGaugeLabel         matlab.ui.control.Label
        BrazointeriorGauge             matlab.ui.control.Gauge
        BrazointeriorGaugeLabel        matlab.ui.control.Label
        AltitudSlider                  matlab.ui.control.Slider
        AltitudSliderLabel             matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        puertoSerie; % Declaración de propiedad para ser usada por toda la aplicación
        cntData=0;
        nBuffer=501;
        nFiltro=5;
        
        buffer_timeStamp=NaN*ones(501,1);
        buffer_tension=NaN*ones(501,1);
        buffer_tensionFilt=NaN*ones(501,1);
        buffer_led=NaN*ones(501,1);
    end
    
    methods (Access = private)
        
        function read_serialData(app, src, event)
            msg=fscanf(app.puertoSerie, '%d, %d, %d');
            app.cntData=app.cntData+1; %Contador de datos recibidos
            
            %Almacenamiento en buffer de los datos recibidos
            if app.cntData<app.nBuffer
                app.buffer_timeStamp(app.cntData)=msg(1)/1000; %Conversión a segundos
                app.buffer_tension(app.cntData)=msg(2)* 5 / 1023; %Conversión a tension
                app.buffer_led(app.cntData)=msg(3);
            else
                app.buffer_timeStamp(end+1)=msg(1)/1000; %Conversión a segundos
                app.buffer_tension(end+1)=msg(2)* 5 / 1023; %Conversión a tensión
                app.buffer_led(end+1)=msg(3);
            end
            
            %Filtro digital de media
            if app.cntData<=app.nFiltro %Filtro digital de media
                app.buffer_tensionFilt(app.cntData)=mean(app.buffer_tension(1:app.cntData));
            else
                app.buffer_tensionFilt(app.cntData)=...
                    mean(app.buffer_tension(app.cntData-app.nFiltro:app.cntData));
            end
            
            %Representación del estado del Led
            %msg(3) indica el estado del led: '1' = On; '0' = Off
            app.LedLamp.Enable=msg(3);
            app.LedLampLabel.Enable=msg(3);
            
            %Representacio del resultado de la señal analógica
            buffer_plot=app.buffer_tension(end-500:end);
            buffer_plot_2=app.buffer_tensionFilt(end-500:end);
            time_array=0:0.01:5;
            
            plot(app.fig_lecturaAnalogica, time_array, buffer_plot, time_array, buffer_plot_2)
            
            app.fig_lecturaAnalogica.XLim=[0,5]; %Se muestra una ventana temporal fija de 5 segundos
            app.fig_lecturaAnalogica.YLim=[0,5]; %La escala de tensión es de 0 a 5V


        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarcomunicacinButton
        function IniciarcomunicacinButtonPushed(app, event)
            serialPort=app.PuertoserieEditField.Value; %Nombre del puerto
            
            %Busco si hay comunicacion abierta previa
            prevDevice=strcat('Serial-', serialPort);
            comPrev=instrfind('Name', prevDevice, 'Status', 'open');
            for i=1:length(comPrev)
                fclose(comPrev(i));
            end
            
            app.puertoSerie=serial(serialPort); %Defino el puerto que abrir
            app.puertoSerie.BaudRate=app.BaudrateEditField.Value; %Establezco velocidad de la comunicación
            
            % Configuración para la recepción de mensajes
            app.puertoSerie.BytesAvailableFcnMode= 'terminator'; %Ejecutar función al encontrar el fin de mensaje
            app.puertoSerie.terminator='LF'; %Fin de mensaje: salto de linea
            app.puertoSerie.BytesAvailableFcn = {@app.read_serialData}; %Handler a la función
           
            
            app.Lamp.Color = 'g';
             fopen(app.puertoSerie);
        end

        % Button pushed function: DetenercomunicacinButton
        function DetenercomunicacinButtonPushed(app, event)
            app.Lamp.Color = [0.80,0.80,0.80];
            fclose(app.puertoSerie);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.BrazoextremoGauge.Value = app.BrazoextremoGauge.Value+app.PosicinengradosEditField.Value;
            app.EditField.Value = app.BrazoextremoGauge.Value;  
            
            angulo_ext = app.BrazoextremoGauge.Value; %Captura del valor del calibre extremo
            msg = ['2,', num2str(angulo_ext)]; %Generación de la cadena de caracteres
            fprintf(app.puertoSerie, msg); %Mensaje para fijar el valor del calibre extremo
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.PosicinengradosEditField_2.Value;
            app.BrazointeriorGauge.Value = app.BrazointeriorGauge.Value+value;
            app.EditField_2.Value = app.BrazointeriorGauge.Value;
            
            angulo_int = app.BrazoextremoGauge.Value; %Captura del valor del calibre interno
            msg = ['3,', num2str(angulo_int)]; %Generación de la cadena de caracteres
            fprintf(app.puertoSerie, msg); %Mensaje para fijar el valor del calibre interno
    
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.AltitudSlider.Value = app.AltitudSlider.Value + app.PosicinenmmEditField.Value;
            app.EditField_3.Value =  app.AltitudSlider.Value;
            
            altitud = app.AltitudSlider.Value; %Captura del valor del altitud
            msg = ['4,', num2str(altitud)]; %Generación de la cadena de caracteres
            fprintf(app.puertoSerie, msg); %Mensaje para fijar el valor del altitud
            
      
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazointeriorGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorGaugeLabel.Text = 'Brazo interior';
            app.BrazointeriorGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorGauge.Limits = [-120 120];
            app.BrazoextremoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoGaugeLabel.Text = 'Brazo extremo';
            app.BrazoextremoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoGauge.Limits = [-120 120];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicinengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField.Value = 15;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicinengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicinenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PinzaSwitch.Items = {'Abierta', 'Cerrada'};
            app.PinzaSwitch.Value = 'Abierta';
            app.Lamp = uilamp(app.UIFigure);
            app.BaudrateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudrateEditFieldLabel.Text = 'Baud rate';
            app.BaudrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuertoserieEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoserieEditFieldLabel.Text = 'Puerto serie';
            app.PuertoserieEditField = uieditfield(app.UIFigure, 'text');
            app.IniciarcomunicacinButton = uibutton(app.UIFigure, 'push');
            app.IniciarcomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcomunicacinButtonPushed, true);
            app.IniciarcomunicacinButton.Text = 'Iniciar comunicación';
            app.DetenercomunicacinButton = uibutton(app.UIFigure, 'push');
            app.DetenercomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercomunicacinButtonPushed, true);
            app.DetenercomunicacinButton.Text = 'Detener comunicación';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end