classdef app_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Direccin0Izquierda1DerechaLabel  matlab.ui.control.Label
        Sentido3                       matlab.ui.control.NumericEditField
        Sentido2                       matlab.ui.control.NumericEditField
        Sentido1                       matlab.ui.control.NumericEditField
        DetenercomunicacinButton       matlab.ui.control.Button
        IniciarcomunicacinButton       matlab.ui.control.Button
        PuertoserieEditField           matlab.ui.control.EditField
        PuertoserieEditFieldLabel      matlab.ui.control.Label
        BaudrateEditField              matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel         matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        PinzaSwitch                    matlab.ui.control.Switch
        PinzaSwitchLabel               matlab.ui.control.Label
        PosicinenmmEditField           matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel      matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        PosicinengradosEditField_2     matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        PosicinengradosEditField       matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        BrazoextremoGauge              matlab.ui.control.Gauge
        BrazoextremoGaugeLabel         matlab.ui.control.Label
        BrazointeriorGauge             matlab.ui.control.Gauge
        BrazointeriorGaugeLabel        matlab.ui.control.Label
        AltitudSlider                  matlab.ui.control.Slider
        AltitudSliderLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        comunicacion % Description
        puertoSerie; % Declaración de propiedad para ser usada por toda la aplicación
        cntData=0;
        nBuffer=501;
        nFiltro=5;
        
        buffer_timeStamp=NaN*ones(501,1);
        buffer_pulsos=NaN*ones(501,1);
        buffer_posi=NaN*ones(501,1);
    end

    methods (Access = private)

        function readArduinoSerialData(app)

            msg=fscanf(app, '%d, %d, %d');
            app.UserData.Count=app.UserData.Count+1; %Contador de datos recibidos

            %Almacenamiento en buffer de los datos recibidos
            if app.UserData<app.nBuffer
                app.buffer_timeStamp(app.cntData)=msg(1)/1000; %Conversión a segundos
                app.buffer_pulsos(app.cntData)=msg(2);
                app.buffer_posi(app.cntData)=msg(3);
            else
                app.buffer_timeStamp(end+1)=msg(1)/1000; %Conversión a segundos
                app.buffer_pulsos(end+1)=msg(2);
                app.buffer_posi(end+1)=msg(3);
            end
            
            %Filtro digital de media
            if app.cntData<=app.nFiltro %Filtro digital de media
                app.buffer_pulsosFilt(app.cntData)=mean(app.buffer_pulsos(1:app.cntData));
            else
                app.buffer_pulsosFilt(app.cntData)=...
                    mean(app.buffer_pulsos(app.cntData-app.nFiltro:app.cntData));
            end
            
            %Representación del estado del Led
            %msg(3) indica el estado del led: '1' = On; '0' = Off
            app.LedLamp.Enable=msg(3);
            app.LedLampLabel.Enable=msg(3);
            
            %Representacio del resultado de la señal analógica
            buffer_plot=app.buffer_pulsos(end-500:end);
            buffer_plot_2=app.buffer_posi(end-500:end);
            time_array=0:0.01:5;
            
            plot(app.UIAxes, time_array, buffer_plot, time_array, buffer_plot_2)
            
            app.UIAxes.XLim=[0,5]; %Se muestra una ventana temporal fija de 5 segundos
            app.UIAxes.YLim=[-1800,1800];


%             while interrupted == 0
%                 data = readline(src);
%                 if data(1:6)==PULSOS
%                     meta = readline(src);
%                     meta = meta(5:strlength(meta));
%                     data = data(6:strlength(data));
%                     src.UserData.Pulsos(end+1) = str2double(data);
%                     src.UserData.Meta(end+1) = str2double(meta);
%                     src.UserData.Count = src.UserData.Count + 1;
%                     plot(app.UIAxes,UserData.Pulsos,"-r");
%                     hold(app.UIAxes);
%                     plot(app.UIAxes,UserData.Meta,"-b");
%                 end
%             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Lamp.Color = [0.80,0.80,0.80];

        end

        % Button pushed function: IniciarcomunicacinButton
        function IniciarcomunicacinButtonPushed(app, event)
            port = app.PuertoserieEditField.Value;
            baudrate = app.BaudrateEditField.Value;
            app.comunicacion = serial(port,'BaudRate',baudrate,'Terminator','CR/LF');
            app.comunicacion.UserData = struct("Pulsos", [],"Meta",[],"Count",1);
            app.Lamp.Color = 'g';
            fopen(app.comunicacion);
            readArduinoSerialData(app);
            interrupted=0;
        end

        % Button pushed function: DetenercomunicacinButton
        function DetenercomunicacinButtonPushed(app, event)
            app.Lamp.Color = [0.80,0.80,0.80];
            fclose(app.comunicacion);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            char a;
            string msg;
            a = int2str(app.BrazoextremoGauge.Value);
            if strcmp(app.Sentido1.Value, 'Izq')
                msg = ['0,',a];
                fprintf(app.comunicacion, msg);

            elseif strcmp(app.Sentido1.Value, 'Der')
                msg = ['1,',a];
                fprintf(app.comunicacion, msg);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.PosicinengradosEditField_2.Value;
            app.BrazointeriorGauge.Value = app.BrazointeriorGauge.Value+value;
            app.Sentido2.Value = app.BrazointeriorGauge.Value;


            global servomotor2
            value = round(app.BrazointeriorGauge.Value);
            servomotor2 = value;

            fprintf(app.comunicacion, 'a');
            fprintf(app.comunicacion,servomotor2,'uint8');
        end

        % Value changed function: Sentido2
        function Sentido2ValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.AltitudSlider.Value = app.AltitudSlider.Value + app.PosicinenmmEditField.Value;
            app.Sentido3.Value =  app.AltitudSlider.Value;


            global DCmotor;
            value = round(app.AltitudSlider.Value); %tenemos que cambiar los mm a grados!!!
            DCmotor = value;

            fprintf(app.comunicacion, 'a');
            fprintf(app.comunicacion,DCmotor,'uint8');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.comunicacion);
            catch Exception
            end
            delete(app.comunicacion);
            delete(app);

        end

        % Value changed function: Sentido1
        function Sentido1ValueChanged(app, event)
            value = app.Sentido1.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazointeriorGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorGaugeLabel.Text = 'Brazo interior';
            app.BrazointeriorGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorGauge.Limits = [-120 120];
            app.BrazoextremoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoGaugeLabel.Text = 'Brazo extremo';
            app.BrazoextremoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoGauge.Limits = [-120 120];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicinengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField.Value = 15;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicinengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicinenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PinzaSwitch.Items = {'Abierta', 'Cerrada'};
            app.PinzaSwitch.Value = 'Abierta';
            app.Lamp = uilamp(app.UIFigure);
            app.BaudrateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudrateEditFieldLabel.Text = 'Baud rate';
            app.BaudrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuertoserieEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoserieEditFieldLabel.Text = 'Puerto serie';
            app.PuertoserieEditField = uieditfield(app.UIFigure, 'text');
            app.IniciarcomunicacinButton = uibutton(app.UIFigure, 'push');
            app.IniciarcomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcomunicacinButtonPushed, true);
            app.IniciarcomunicacinButton.Text = 'Iniciar comunicación';
            app.DetenercomunicacinButton = uibutton(app.UIFigure, 'push');
            app.DetenercomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercomunicacinButtonPushed, true);
            app.DetenercomunicacinButton.Text = 'Detener comunicación';
            app.Sentido1 = uieditfield(app.UIFigure, 'numeric');
            app.Sentido1.ValueChangedFcn = createCallbackFcn(app, @Sentido1ValueChanged, true);
            app.Sentido2 = uieditfield(app.UIFigure, 'numeric');
            app.Sentido2.ValueChangedFcn = createCallbackFcn(app, @Sentido2ValueChanged, true);
            app.Sentido3 = uieditfield(app.UIFigure, 'numeric');
            app.Direccin0Izquierda1DerechaLabel = uilabel(app.UIFigure);
            app.Direccin0Izquierda1DerechaLabel.Text = {'Dirección:'; '0 - Izquierda'; '1 - Derecha'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end