classdef grupo8_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PosicionesdelrobotactualLabel   matlab.ui.control.Label
        PinzaEditField                  matlab.ui.control.NumericEditField
        PinzaEditFieldLabel             matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        Q2EditField                     matlab.ui.control.NumericEditField
        Q2EditFieldLabel                matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        Q1EditField                     matlab.ui.control.NumericEditField
        Q1EditFieldLabel                matlab.ui.control.Label
        ListadeposicionesguardadasLabel  matlab.ui.control.Label
        VelocidadconstanteButtonGroup   matlab.ui.container.ButtonGroup
        CartesianaButton                matlab.ui.control.RadioButton
        ArticularButton                 matlab.ui.control.RadioButton
        ymen                            matlab.ui.control.Button
        ymas                            matlab.ui.control.Button
        xmen                            matlab.ui.control.Button
        xmas                            matlab.ui.control.Button
        Velocidad3EditField             matlab.ui.control.NumericEditField
        Velocidad3EditFieldLabel        matlab.ui.control.Label
        Velocidad2EditField             matlab.ui.control.NumericEditField
        Velocidad2EditFieldLabel        matlab.ui.control.Label
        CopyrighLabel                   matlab.ui.control.Label
        ConfirmarMovimientoButton       matlab.ui.control.Button
        Velocidad1EditField             matlab.ui.control.NumericEditField
        Velocidad1EditFieldLabel        matlab.ui.control.Label
        MovimientoZSlider               matlab.ui.control.Slider
        MovimientoZSliderLabel          matlab.ui.control.Label
        MovimientoYSlider               matlab.ui.control.Slider
        MovientoYSliderLabel            matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        PlanearposicinButton            matlab.ui.control.Button
        NombreEditField                 matlab.ui.control.EditField
        GuardarposcionButton            matlab.ui.control.Button
        MovimientoplaneadoTextArea      matlab.ui.control.TextArea
        MovimientoplaneadoTextAreaLabel  matlab.ui.control.Label
        SeleccionararchivoButton        matlab.ui.control.Button
        PosicionesguardadasDropDown     matlab.ui.control.DropDown
        PosicionesguardadasDropDownLabel  matlab.ui.control.Label
        zmen                            matlab.ui.control.Button
        zmas                            matlab.ui.control.Button
        imen                            matlab.ui.control.Button
        imas                            matlab.ui.control.Button
        dmen                            matlab.ui.control.Button
        dmas                            matlab.ui.control.Button
        MovimientoXSlider               matlab.ui.control.Slider
        MovimientoXSliderLabel          matlab.ui.control.Label
        GoHomeButton                    matlab.ui.control.Button
        PosicionYField_2                matlab.ui.control.NumericEditField
        PosicinxLabel_4                 matlab.ui.control.Label
        PosicionXField_2                matlab.ui.control.NumericEditField
        PosicinxLabel_3                 matlab.ui.control.Label
        MoviendoZ                       matlab.ui.control.Lamp
        MoviendoLamp_3Label             matlab.ui.control.Label
        MoviendoI                       matlab.ui.control.Lamp
        MoviendoLamp_2Label             matlab.ui.control.Label
        MoviendoD                       matlab.ui.control.Lamp
        MoviendoLampLabel               matlab.ui.control.Label
        ConfirmarTrayectoriaButton      matlab.ui.control.Button
        PosicionYField                  matlab.ui.control.NumericEditField
        PosicinxLabel_2                 matlab.ui.control.Label
        PosicionXField                  matlab.ui.control.NumericEditField
        PosicinxLabel                   matlab.ui.control.Label
        ModoButtonGroup                 matlab.ui.container.ButtonGroup
        JacobianaButton                 matlab.ui.control.RadioButton
        TraytrapezoidalButton           matlab.ui.control.RadioButton
        TrayectorialinearButton         matlab.ui.control.RadioButton
        CinInversaButton                matlab.ui.control.RadioButton
        CinDirectaButton                matlab.ui.control.RadioButton
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        ConfirmarButton                 matlab.ui.control.Button
        Giro                            matlab.ui.control.NumericEditField
        PinzaGauge                      matlab.ui.control.Gauge
        PinzagiroGaugeLabel             matlab.ui.control.Label
        PinzaSwitch                     matlab.ui.control.RockerSwitch
        PinzaSwitchLabel                matlab.ui.control.Label
        Comunicacion                    matlab.ui.control.Lamp
        PosicionenmmEditField           matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel       matlab.ui.control.Label
        PosicionengradosEditField_2     matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        PosicionengradosEditField       matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel   matlab.ui.control.Label
        BrazointeriorMotorderechoGauge  matlab.ui.control.Gauge
        BrazointeriorMotorderechoGaugeLabel  matlab.ui.control.Label
        BrazoextremoMotorizquierdoGauge  matlab.ui.control.Gauge
        BrazoextremoMotorizquierdoGaugeLabel  matlab.ui.control.Label
        AltitudSlider                   matlab.ui.control.Slider
        AltitudSliderLabel              matlab.ui.control.Label
        DetenerComunicacion_Button      matlab.ui.control.Button
        IniciarComunicacionButton       matlab.ui.control.Button
        BaudRate                        matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel          matlab.ui.control.Label
        SerialPort                      matlab.ui.control.EditField
        SerialPortEditFieldLabel        matlab.ui.control.Label
        ArduinoControlconMATLABLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TrayPuntosAxes                  matlab.ui.control.UIAxes
        AxesExtremo                     matlab.ui.control.UIAxes
        AxesStepper                     matlab.ui.control.UIAxes
        AxesInterior                    matlab.ui.control.UIAxes
    end


    % Matlab Interface for Arduino Robot Control
    %
    % Version 24.11.2022
    %
    % Grupo 8

    properties (Access = private)
        puertoSerie; % Declaración de propiedad para ser usada por toda la aplicación
        fid;

        factor=1579;
        trayectoria_input;
        mainplot;
        waitlist = {};

        postion_array;

        isMovingD=false;
        isMovingI=false;
        isMovingZ=false;
        diffLimit=4;
        diffLimitZ=1;
        mode = 0;
        x,y=0;
        p;
        tray_real;
        v_x;
        v_y;
        xinput = 0;
        yinput = 0;
        zinput = 0;
        dnow;
        inow;
        znow;
        inputtable;

        cntData1=0;
        nBuffer1=1001;
        buffer1_timeStamp=NaN*ones(1001,1);
        buffer1_pulsos=NaN*ones(1001,1);
        buffer1_posi=NaN*ones(1001,1);

        cntData2=0;
        nBuffer2=1001;
        buffer2_timeStamp=NaN*ones(1001,1);
        buffer2_pulsos=NaN*ones(1001,1);
        buffer2_posi=NaN*ones(1001,1);

        cntData3=0;
        nBuffer3=1001;
        buffer3_timeStamp=NaN*ones(1001,1);
        buffer3_pulsos=NaN*ones(1001,1);
        buffer3_posi=NaN*ones(1001,1);
    end

    methods (Access = private)

        function read_serialData(app, ~, ~)
            try
                msg=fscanf(app.puertoSerie, '%d, %d, %f, %f');

                % opcode format: motor, timestamp, pulsos, posi
                % opcode error: 4, motornumber,

                switch msg(1)
                    case 1
                        app.cntData1=app.cntData1+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        app.dnow = msg(3);
                        app.Q1EditField.Value = app.dnow;
                        app.BrazointeriorMotorderechoGauge.Value = app.dnow;
                        if app.cntData1<app.nBuffer1
                            app.buffer1_timeStamp(app.cntData1)=msg(2)/1000; %Conversión a segundos
                            app.buffer1_pulsos(app.cntData1)=msg(3);
                            app.buffer1_posi(app.cntData1)=msg(4);
                        else
                            app.buffer1_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer1_pulsos(end+1)=msg(3);
                            app.buffer1_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimit
                            app.isMovingD=false;
                            app.MoviendoD.Color='red';
                        else 
                            app.isMovingD=true;
                            app.MoviendoD.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer1_plot=app.buffer1_pulsos(end-1000:end);
                        buffer1_plot_2=app.buffer1_posi(end-1000:end);
                        time1_array=0:0.01:10;

                        plot(app.AxesExtremo, time1_array, buffer1_plot, time1_array, buffer1_plot_2)
                    case 2
                        app.cntData2=app.cntData2+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        app.inow = msg(3);
                        app.Q2EditField.Value = app.inow;
                        app.BrazoextremoMotorizquierdoGauge.Value = app.inow;
                        if app.cntData2<app.nBuffer2
                            app.buffer2_timeStamp(app.cntData2)=msg(2)/1000; %Conversión a segundos
                            app.buffer2_pulsos(app.cntData2)=msg(3);
                            app.buffer2_posi(app.cntData2)=msg(4);
                        else
                            app.buffer2_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer2_pulsos(end+1)=msg(3);
                            app.buffer2_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimit
                            app.isMovingI=false;
                            app.MoviendoI.Color='red';
                        else 
                            app.isMovingI=true;
                            app.MoviendoI.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer2_plot=app.buffer2_pulsos(end-1000:end);
                        buffer2_plot_2=app.buffer2_posi(end-1000:end);
                        time2_array=0:0.01:10;

                        plot(app.AxesInterior, time2_array, buffer2_plot, time2_array, buffer2_plot_2)
                    case 3
                        app.cntData3=app.cntData3+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        app.znow = msg(3);
                        app.ZEditField.Value = app.znow;
                        app.AltitudSlider.Value = app.znow;
                        if app.cntData3<app.nBuffer3
                            app.buffer3_timeStamp(app.cntData3)=msg(2)/1000; %Conversión a segundos
                            app.buffer3_pulsos(app.cntData3)=msg(3);
                            app.buffer3_posi(app.cntData3)=msg(4);
                        else
                            app.buffer3_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer3_pulsos(end+1)=msg(3);
                            app.buffer3_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimitZ
                            app.isMovingZ=false;
                            app.MoviendoZ.Color='red';
                        else 
                            app.isMovingZ=true;
                            app.MoviendoZ.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer3_plot=app.buffer3_pulsos(end-1000:end);
                        buffer3_plot_2=app.buffer3_posi(end-1000:end);
                        time3_array=0:0.01:10;

                        plot(app.AxesStepper, time3_array, buffer3_plot, time3_array, buffer3_plot_2)
                    case 4
                        app.LogTextArea.Value = ["Error: pulsos fuera de campo de trabajo de motor ", msg(2), "pulsos: ", msg(3), "posi: ", msg(4)];
                    case 5
                        app.LogTextArea.Value = ["Error: Punto de Singularidad", msg(2), "x: ", msg(3), "y: ", msg(4)];
                    case 6
                        app.xinput = msg(2);
                        app.yinput = msg(3);
                        app.zinput = app.znow;
%                         if((app.mode == 2)&&(app.isMovingD==false && app.isMovingI==false && app.isMovingZ==false))
%                             addpoints(app.tray_real, app.xinput, app.yinput);
%                         end

                        app.XEditField_2.Value=app.xinput;
                        app.YEditField.Value=app.yinput;
                        app.PinzaEditField.Value = app.Giro.Value;

                        addpoints(app.mainplot, app.xinput, app.yinput, app.zinput);
                        drawnow;
                        hold(app.UIAxes, 'on');
                        plot3(app.UIAxes, app.xinput, app.yinput, app.zinput, 'Marker', 'o', 'Color', 'red');
                        hold(app.UIAxes, 'off');
                
                end
            catch
                return;

            end

        end

        function [result] = sentido2sign(~, sentido)
            if sentido == 0
                result = -1;
            else
                result = 1;
            end
        end

        function sendCommands(app)
            %app.mode;
            if((app.PosicionengradosEditField.Value == 0) && (app.PosicionengradosEditField_2.Value == 0))
                app.LogTextArea.Value = "El Robot pasa por un punto singular (0,0)";
            end
            opcode = [num2str(app.mode), ',',num2str(app.PosicionengradosEditField.Value), ',', num2str(app.PosicionengradosEditField_2.Value), ',', num2str(app.PosicionenmmEditField.Value), ',', num2str(app.Giro.Value), ',', num2str(app.PinzaSwitch.Value), ',', num2str(app.PosicionXField.Value), ',', num2str(app.PosicionYField.Value), ',', num2str(app.PosicionXField_2.Value), ',', num2str(app.PosicionYField_2.Value), ',' , num2str(app.Velocidad1EditField.Value), ',', num2str(app.Velocidad2EditField.Value), ',', num2str(app.Velocidad3EditField.Value)];
            fprintf(app.puertoSerie, opcode);
            disp(opcode);
            %             pause(2);
%             app.PosicionXField.Value = app.xinput;
%             app.PosicionYField.Value = app.yinput;
%             app.PosicionenmmEditField.Value = app.znow;
%             app.PosicionengradosEditField.Value = app.dnow;
%             app.PosicionengradosEditField_2.Value = app.inow;
        end

        function fileToPosition(app, file)
            app.inputtable = readtable(file, 'ReadRowNames',true);
            app.inputtable.Properties.VariableNames = ["Mode", "Grados D", "Grados I", "mm", "Giro", "Pinza", "xA", "yA", "xB", "yB"];
            app.UITable.Data = app.inputtable;
            app.UITable.ColumnName = app.inputtable.Properties.VariableNames;
            app.UITable.RowName = app.inputtable.Properties.RowNames;
        end

        function mandarPlaneado(app)
            for i = 1:(length(app.waitlist))
                app.PosicionesguardadasDropDown.Value = app.waitlist{i};
                disp("text")
                PosicionesguardadasDropDownValueChanged(app, app);
                sendCommands(app);
                pause(1);
                while (app.isMovingD==true || app.isMovingI==true || app.isMovingZ==true)
                    pause(0.1);
                end
            end
        end

        function mandarVelocidades(app)
            prevmode = app.mode;
            if app.ArticularButton.Value == 1
                app.mode = 5;
            elseif app.CartesianaButton.Value == 1
                app.mode = 6;
            end
            sendCommands(app);
            app.mode = prevmode;
        end 

        function sendTray(app,x,y)
            for c = 1:(length(app.x)-1)
                app.PosicionXField.Value=x(c);
                app.PosicionXField_2.Value=x(c+1);
                app.PosicionYField.Value=y(c);
                app.PosicionYField_2.Value=y(c+1);
                sendCommands(app);
                pause(1);
                while (app.isMovingD || app.isMovingI || app.isMovingZ)
                    pause(0.1);
                end
                addpoints(app.tray_real, app.xinput, app.yinput);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load saved positions
            fileToPosition(app,'positions.txt');
            app.PosicionesguardadasDropDown.Items = app.inputtable.Properties.RowNames;
            app.fid = fopen('positions.txt', 'a+');
            % draw mainplot
            app.mainplot = animatedline(app.UIAxes, app.xinput, app.yinput, app.zinput);
            view(app.UIAxes, 3);
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: IniciarComunicacionButton
        function IniciarComunicacionButtonPushed(app, event)

            app.LogTextArea.Value = "Iniciando Comuniación...";

            serialPort=app.SerialPort.Value; %Nombre del puerto

            %Busco si hay comunicacion abierta previa
            prevDevice=strcat('Serial-', serialPort);
            comPrev=instrfind('Name', prevDevice, 'Status', 'open');
            for i=1:length(comPrev)
                fclose(comPrev(i));
            end

            app.puertoSerie=serial(serialPort); %Defino el puerto que abrir
            app.puertoSerie.BaudRate=app.BaudRate.Value; %Establezco velocidad de la comunicación

            % Configuración para la recepción de mensajes
            app.puertoSerie.BytesAvailableFcnMode= 'terminator'; %Ejecutar función al encontrar el fin de mensaje
            app.puertoSerie.terminator='LF'; %Fin de mensaje: salto de linea

            app.puertoSerie.BytesAvailableFcn = {@app.read_serialData}; %Handler a la función
            fopen(app.puertoSerie); %Abro comunicacion

            app.LogTextArea.Value = "Comuniación iniciada!";
            app.Comunicacion.Color = 'green';
        end

        % Button pushed function: DetenerComunicacion_Button
        function DetenerComunicacion_ButtonPushed(app, event)
            app.goHome();
            pause(5);
            fclose(app.puertoSerie);
            app.Comunicacion.Color = 'red';

            assignin('base', 'buffer1_posi', app.buffer1_posi);
            assignin('base', 'buffer2_posi', app.buffer2_posi);
            assignin('base', 'buffer3_posi', app.buffer3_posi);
            assignin('base', 'buffer1_pulsos', app.buffer1_pulsos);
            assignin('base', 'buffer2_pulsos', app.buffer2_pulsos);
            assignin('base', 'buffer3_pulsos', app.buffer3_pulsos);
            assignin('base', 'buffer1_timeStamp', app.buffer1_timeStamp);
            assignin('base', 'buffer2_timeStamp', app.buffer2_timeStamp);
            assignin('base', 'buffer3_timeStamp', app.buffer3_timeStamp);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.fid);
                fclose(app.puertoSerie);
            end
            delete(app)
        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonButtonPushed(app, event)
            mandarVelocidades(app);
            pause(0.2);
            sendCommands(app);
            app.PinzaGauge.Value=app.Giro.Value;
            app.BrazointeriorMotorderechoGauge.Value=app.PosicionengradosEditField.Value;
            app.BrazoextremoMotorizquierdoGauge.Value=app.PosicionengradosEditField_2.Value;
            app.AltitudSlider.Value=app.PosicionenmmEditField.Value;

        end

        % Selection changed function: ModoButtonGroup
        function ModoButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ModoButtonGroup.SelectedObject;
            if app.CinInversaButton.Value
                app.mode=1;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="off";
                app.ConfirmarTrayectoriaButton.Enable="off";
            end
           if app.CinDirectaButton.Value
                app.mode=0;
                app.PosicionengradosEditField.Enable="on";
                app.PosicionengradosEditField_2.Enable="on";
                app.PosicionXField.Enable="off";
                app.PosicionYField.Enable="off";
                app.PosicionXField_2.Enable="off";
                app.PosicionYField_2.Enable="off";
                app.TrayPuntosAxes.Visible="off";
                app.ConfirmarTrayectoriaButton.Enable="off";
           end
           if app.TrayectorialinearButton.Value
                app.mode=2;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";  
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="on";
                app.ConfirmarTrayectoriaButton.Enable="on";
           end           
           if app.TraytrapezoidalButton.Value
                app.mode=4;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";  
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="on";
                app.ConfirmarTrayectoriaButton.Enable="on";
           end           
           if app.JacobianaButton.Value
                app.mode=3;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";  
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="on";
                app.ConfirmarTrayectoriaButton.Enable="on";
           end
        end

        % Button down function: TrayPuntosAxes
        function TrayPuntosAxesButtonDown(app, event)
            delete(app.p);
            img = imread('Robot.png');
            % set(axes, 'YDir','normal')
            imshow(flipud(img), 'XData',0:400, 'YData',0:339);
            axis on;
            gca().set("YDir", 'normal');
            %RI = imref2d(size(img));
            %RI.XWorldLimits = [0 480];
            %RI.YWorldLimits = [0 407];
            %imshow(flipud(img), RI);
            [app.x,app.y]=ginput;
            app.x = round(app.x - 200);
            app.y = round(app.y -141);
            app.p = plot(app.TrayPuntosAxes, app.x, app.y);
            app.tray_real = animatedline(app.TrayPuntosAxes, app.xinput, app.yinput, 'Marker','o');
            close
        end

        % Button pushed function: ConfirmarTrayectoriaButton
        function ConfirmarTrayectoriaButtonPushed(app, event)
            sendTray(app, app.x, app.y);
            %polybuffer(app.trayectoria_input);
            app.PosicionXField_2.Editable="on";
            app.PosicionYField_2.Editable="on";            
            app.PosicionXField.Editable="on";
            app.PosicionYField.Editable="on";
        end

        % Button pushed function: GoHomeButton
        function goHome(app, event)
            opcode = "0,0,0,0,0,0,0,0,0,0,0,0,0";
            fprintf(app.puertoSerie, opcode);
            disp(opcode);
        end

        % Value changed function: MovimientoXSlider
        function MovimientoXSliderValueChanged(app, event)
            app.MovimientoXSlider.Value=0;
        end

        % Value changing function: MovimientoXSlider
        function MovimientoXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PosicionXField.Value= 0.1*changingValue+app.PosicionXField.Value;
            app.mode=1;
            sendCommands(app);
        end

        % Button pushed function: dmas
        function dmasButtonPushed(app, event)
            app.PosicionengradosEditField.Value = app.PosicionengradosEditField.Value +1;
            app.mode=0;
            sendCommands(app);
        end

        % Button pushed function: dmen
        function dmenButtonPushed(app, event)
            app.PosicionengradosEditField.Value = app.PosicionengradosEditField.Value -1;
            app.mode=0;
            sendCommands(app);
        end

        % Button pushed function: imas
        function imasButtonPushed(app, event)
            app.PosicionengradosEditField_2.Value = app.PosicionengradosEditField_2.Value +1;
            app.mode=0;
            sendCommands(app);
        end

        % Button pushed function: imen
        function imenButtonPushed(app, event)
            app.PosicionengradosEditField_2.Value = app.PosicionengradosEditField_2.Value -1;
            app.mode=0;
            sendCommands(app);
        end

        % Button pushed function: zmas
        function zmasButtonPushed(app, event)
            app.PosicionenmmEditField.Value = app.PosicionenmmEditField.Value +1;
            sendCommands(app);
        end

        % Button pushed function: zmen
        function zmenButtonPushed(app, event)
            app.PosicionenmmEditField.Value = app.PosicionenmmEditField.Value -1;
            sendCommands(app);
        end

        % Button pushed function: GuardarposcionButton
        function GuardarposcionButtonPushed(app, event)
            %formatSpec = '%.2f';
            position_store = [app.mode app.PosicionengradosEditField.Value app.PosicionengradosEditField_2.Value app.PosicionenmmEditField.Value app.Giro.Value app.PinzaSwitch.Value app.PosicionXField.Value app.PosicionYField.Value app.PosicionXField_2.Value app.PosicionYField_2.Value];
            %position_store = [num2str(app.mode), ',',num2str(app.PosicionengradosEditField.Value, formatSpec), ',', num2str(app.PosicionengradosEditField_2.Value, formatSpec), ',', num2str(app.PosicionenmmEditField.Value, formatSpec), ',', num2str(app.Giro.Value, formatSpec), ',', num2str(app.PinzaSwitch.Value, formatSpec), ',', num2str(app.PosicionXField.Value, formatSpec), ',', num2str(app.PosicionYField.Value, formatSpec), ',', num2str(app.PosicionXField_2.Value, formatSpec), ',', num2str(app.PosicionYField_2.Value,formatSpec)]
            %position_store = [1 2 3 4 5 6 7 8 9 10];
            fprintf(app.fid, '\n%s', app.NombreEditField.Value);
            fprintf(app.fid, ',%d,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f', position_store);
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            secuence = readtable(file,'Delimiter', '\n','Format','%s','ReadVariableNames', false);
            app.waitlist = table2array(secuence);
            disp(app.waitlist)
            app.MovimientoplaneadoTextArea.Value = app.waitlist;
        end

        % Value changed function: PosicionesguardadasDropDown
        function PosicionesguardadasDropDownValueChanged(app, event)
            value = app.PosicionesguardadasDropDown.Value;
            app.mode = app.inputtable{value,1};
            switch (app.mode)
                case 2
                    set(app.TrayectorialinearButton, 'Value', 1);
                case 1
                    set(app.CinInversaButton, 'Value', 1);                
                case 0
                    set(app.CinDirectaButton, 'Value', 1);
            end
            app.PosicionengradosEditField.Value = app.inputtable{value,2};
            app.PosicionengradosEditField_2.Value = app.inputtable{value,3};
            app.PosicionenmmEditField.Value = app.inputtable{value,4};
            app.Giro.Value= app.inputtable{value,5};
            app.PinzaSwitch.Value= app.inputtable{value,6};
            app.PosicionXField.Value= app.inputtable{value,7};
            app.PosicionYField.Value= app.inputtable{value,8};
            app.PosicionXField_2.Value= app.inputtable{value,9};
            app.PosicionYField_2.Value= app.inputtable{value,10};
        end

        % Button pushed function: PlanearposicinButton
        function PlanearposicinButtonPushed(app, event)
            app.waitlist = [app.waitlist, app.PosicionesguardadasDropDown.Value];
            app.MovimientoplaneadoTextArea.Value = app.waitlist;
        end

        % Button pushed function: xmas
        function xmasButtonPushed(app, event)
            app.PosicionXField.Value = app.PosicionXField.Value +1;
            app.mode=1;
            sendCommands(app);
        end

        % Button pushed function: xmen
        function xmenButtonPushed(app, event)
            app.PosicionXField.Value = app.PosicionXField.Value -1;
            app.mode=1;
            sendCommands(app);
        end

        % Button pushed function: ymas
        function ymasButtonPushed(app, event)
            app.PosicionYField.Value = app.PosicionYField.Value +1;
            app.mode=1;
            sendCommands(app);
        end

        % Button pushed function: ymen
        function ymenButtonPushed(app, event)
            app.PosicionYField.Value = app.PosicionYField.Value -1;
            app.mode=1;
            sendCommands(app);
        end

        % Value changed function: MovimientoYSlider
        function MovimientoYSliderValueChanged(app, event)
            app.MovimientoYSlider.Value = 0;
        end

        % Value changing function: MovimientoYSlider
        function MovimientoYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PosicionYField.Value= 0.1*changingValue+app.PosicionYField.Value;
            app.mode=1;
            sendCommands(app);
        end

        % Value changed function: MovimientoZSlider
        function MovimientoZSliderValueChanged(app, event)
            app.MovimientoZSlider.Value = 0;
        end

        % Value changing function: MovimientoZSlider
        function MovimientoZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PosicionenmmEditField.Value= 0.1*changingValue+app.PosicionenmmEditField.Value;
            app.mode=1;
            sendCommands(app);
        end

        % Button pushed function: ConfirmarMovimientoButton
        function ConfirmarMovimientoButtonPushed(app, event)
            mandarPlaneado(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AxesInterior = uiaxes(app.UIFigure);
            title(app.AxesInterior, 'PID 2')
            xlabel(app.AxesInterior, 'X')
            ylabel(app.AxesInterior, 'Y')
            zlabel(app.AxesInterior, 'Z')
            app.AxesStepper = uiaxes(app.UIFigure);
            title(app.AxesStepper, 'Paso a Paso')
            xlabel(app.AxesStepper, 'X')
            ylabel(app.AxesStepper, 'Y')
            zlabel(app.AxesStepper, 'Z')
            app.AxesExtremo = uiaxes(app.UIFigure);
            title(app.AxesExtremo, 'PID 1')
            xlabel(app.AxesExtremo, 'X')
            ylabel(app.AxesExtremo, 'Y')
            zlabel(app.AxesExtremo, 'Z')
            app.TrayPuntosAxes = uiaxes(app.UIFigure);
            title(app.TrayPuntosAxes, 'Campo de Trabajo')
            xlabel(app.TrayPuntosAxes, 'X')
            ylabel(app.TrayPuntosAxes, 'Y')
            zlabel(app.TrayPuntosAxes, 'Z')
            app.TrayPuntosAxes.ButtonDownFcn = createCallbackFcn(app, @TrayPuntosAxesButtonDown, true);
            app.TrayPuntosAxes.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Posición del robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ArduinoControlconMATLABLabel = uilabel(app.UIFigure);
            app.ArduinoControlconMATLABLabel.Text = 'Arduino - Control con MATLAB';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPort = uieditfield(app.UIFigure, 'text');
            app.SerialPort.Value = 'COM12';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.Value = 9600;
            app.IniciarComunicacionButton = uibutton(app.UIFigure, 'push');
            app.IniciarComunicacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarComunicacionButtonPushed, true);
            app.IniciarComunicacionButton.Text = 'Iniciar Comunicacion';
            app.DetenerComunicacion_Button = uibutton(app.UIFigure, 'push');
            app.DetenerComunicacion_Button.ButtonPushedFcn = createCallbackFcn(app, @DetenerComunicacion_ButtonPushed, true);
            app.DetenerComunicacion_Button.Text = 'Detener Comunicacion';
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazoextremoMotorizquierdoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoMotorizquierdoGaugeLabel.Text = {'Brazo extremo'; 'Motor izquierdo'};
            app.BrazoextremoMotorizquierdoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoMotorizquierdoGauge.Limits = [-95 95];
            app.BrazointeriorMotorderechoGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorMotorderechoGaugeLabel.Text = {'Brazo interior'; 'Motor derecho'};
            app.BrazointeriorMotorderechoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorMotorderechoGauge.Limits = [-140 140];
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicionengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicionengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicionenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Comunicacion = uilamp(app.UIFigure);
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'rocker');
            app.PinzaSwitch.Items = {'Abierta,', 'Cerrada'};
            app.PinzaSwitch.ItemsData = [1 0];
            app.PinzaSwitch.Value = 0;
            app.PinzagiroGaugeLabel = uilabel(app.UIFigure);
            app.PinzagiroGaugeLabel.Text = 'Pinza giro';
            app.PinzaGauge = uigauge(app.UIFigure, 'circular');
            app.PinzaGauge.Limits = [-175 175];
            app.Giro = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmarButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.ModoButtonGroup = uibuttongroup(app.UIFigure);
            app.ModoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroupSelectionChanged, true);
            app.ModoButtonGroup.Title = 'Modo';
            app.ModoButtonGroup.Tag = '0';
            app.CinDirectaButton = uiradiobutton(app.ModoButtonGroup);
            app.CinDirectaButton.Text = 'Cin. Directa';
            app.CinDirectaButton.Value = true;
            app.CinInversaButton = uiradiobutton(app.ModoButtonGroup);
            app.CinInversaButton.Tag = '1';
            app.CinInversaButton.Text = 'Cin. Inversa';
            app.TrayectorialinearButton = uiradiobutton(app.ModoButtonGroup);
            app.TrayectorialinearButton.Tag = '2';
            app.TrayectorialinearButton.Text = 'Trayectoria linear';
            app.TraytrapezoidalButton = uiradiobutton(app.ModoButtonGroup);
            app.TraytrapezoidalButton.Tag = '2';
            app.TraytrapezoidalButton.Text = 'Tray. trapezoidal';
            app.JacobianaButton = uiradiobutton(app.ModoButtonGroup);
            app.JacobianaButton.Tag = '2';
            app.JacobianaButton.Visible = 'off';
            app.JacobianaButton.Text = 'Jacobiana';
            app.PosicinxLabel = uilabel(app.UIFigure);
            app.PosicinxLabel.Enable = 'off';
            app.PosicinxLabel.Text = 'Posición (x_a)';
            app.PosicionXField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXField.Enable = 'off';
            app.PosicinxLabel_2 = uilabel(app.UIFigure);
            app.PosicinxLabel_2.Enable = 'off';
            app.PosicinxLabel_2.Text = 'Posición (y_a)';
            app.PosicionYField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYField.Enable = 'off';
            app.ConfirmarTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarTrayectoriaButtonPushed, true);
            app.ConfirmarTrayectoriaButton.Text = 'Confirmar Trayectoria';
            app.MoviendoLampLabel = uilabel(app.UIFigure);
            app.MoviendoLampLabel.Text = 'Moviendo';
            app.MoviendoD = uilamp(app.UIFigure);
            app.MoviendoLamp_2Label = uilabel(app.UIFigure);
            app.MoviendoLamp_2Label.Text = 'Moviendo';
            app.MoviendoI = uilamp(app.UIFigure);
            app.MoviendoLamp_3Label = uilabel(app.UIFigure);
            app.MoviendoLamp_3Label.Text = 'Moviendo';
            app.MoviendoZ = uilamp(app.UIFigure);
            app.PosicinxLabel_3 = uilabel(app.UIFigure);
            app.PosicinxLabel_3.Enable = 'off';
            app.PosicinxLabel_3.Text = 'Posición (x_b)';
            app.PosicionXField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXField_2.Enable = 'off';
            app.PosicinxLabel_4 = uilabel(app.UIFigure);
            app.PosicinxLabel_4.Enable = 'off';
            app.PosicinxLabel_4.Text = 'Posición (y_b)';
            app.PosicionYField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYField_2.Enable = 'off';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @goHome, true);
            app.GoHomeButton.Text = 'Go Home';
            app.MovimientoXSliderLabel = uilabel(app.UIFigure);
            app.MovimientoXSliderLabel.Text = 'Movimiento X';
            app.MovimientoXSlider = uislider(app.UIFigure);
            app.MovimientoXSlider.Limits = [-10 10];
            app.MovimientoXSlider.ValueChangedFcn = createCallbackFcn(app, @MovimientoXSliderValueChanged, true);
            app.MovimientoXSlider.ValueChangingFcn = createCallbackFcn(app, @MovimientoXSliderValueChanging, true);
            app.dmas = uibutton(app.UIFigure, 'push');
            app.dmas.ButtonPushedFcn = createCallbackFcn(app, @dmasButtonPushed, true);
            app.dmas.Text = '+';
            app.dmen = uibutton(app.UIFigure, 'push');
            app.dmen.ButtonPushedFcn = createCallbackFcn(app, @dmenButtonPushed, true);
            app.dmen.Text = '-';
            app.imas = uibutton(app.UIFigure, 'push');
            app.imas.ButtonPushedFcn = createCallbackFcn(app, @imasButtonPushed, true);
            app.imas.Text = '+';
            app.imen = uibutton(app.UIFigure, 'push');
            app.imen.ButtonPushedFcn = createCallbackFcn(app, @imenButtonPushed, true);
            app.imen.Text = '-';
            app.zmas = uibutton(app.UIFigure, 'push');
            app.zmas.ButtonPushedFcn = createCallbackFcn(app, @zmasButtonPushed, true);
            app.zmas.Text = '+';
            app.zmen = uibutton(app.UIFigure, 'push');
            app.zmen.ButtonPushedFcn = createCallbackFcn(app, @zmenButtonPushed, true);
            app.zmen.Text = '-';
            app.PosicionesguardadasDropDownLabel = uilabel(app.UIFigure);
            app.PosicionesguardadasDropDownLabel.Text = 'Posiciones guardadas';
            app.PosicionesguardadasDropDown = uidropdown(app.UIFigure);
            app.PosicionesguardadasDropDown.Items = {};
            app.PosicionesguardadasDropDown.ValueChangedFcn = createCallbackFcn(app, @PosicionesguardadasDropDownValueChanged, true);
            app.PosicionesguardadasDropDown.Placeholder = 'Posicion';
            app.PosicionesguardadasDropDown.Value = {};
            app.SeleccionararchivoButton = uibutton(app.UIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.MovimientoplaneadoTextAreaLabel = uilabel(app.UIFigure);
            app.MovimientoplaneadoTextAreaLabel.Text = {'Movimiento'; 'planeado'};
            app.MovimientoplaneadoTextArea = uitextarea(app.UIFigure);
            app.GuardarposcionButton = uibutton(app.UIFigure, 'push');
            app.GuardarposcionButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarposcionButtonPushed, true);
            app.GuardarposcionButton.Text = 'Guardar poscion';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.NombreEditField.Placeholder = 'Nombre';
            app.PlanearposicinButton = uibutton(app.UIFigure, 'push');
            app.PlanearposicinButton.ButtonPushedFcn = createCallbackFcn(app, @PlanearposicinButtonPushed, true);
            app.PlanearposicinButton.Text = 'Planear posición ';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MovientoYSliderLabel = uilabel(app.UIFigure);
            app.MovientoYSliderLabel.Text = 'Moviento Y';
            app.MovimientoYSlider = uislider(app.UIFigure);
            app.MovimientoYSlider.Limits = [-10 10];
            app.MovimientoYSlider.ValueChangedFcn = createCallbackFcn(app, @MovimientoYSliderValueChanged, true);
            app.MovimientoYSlider.ValueChangingFcn = createCallbackFcn(app, @MovimientoYSliderValueChanging, true);
            app.MovimientoZSliderLabel = uilabel(app.UIFigure);
            app.MovimientoZSliderLabel.Text = 'Movimiento Z';
            app.MovimientoZSlider = uislider(app.UIFigure);
            app.MovimientoZSlider.Limits = [-10 10];
            app.MovimientoZSlider.ValueChangedFcn = createCallbackFcn(app, @MovimientoZSliderValueChanged, true);
            app.MovimientoZSlider.ValueChangingFcn = createCallbackFcn(app, @MovimientoZSliderValueChanging, true);
            app.Velocidad1EditFieldLabel = uilabel(app.UIFigure);
            app.Velocidad1EditFieldLabel.Text = 'Velocidad 1 [%]';
            app.Velocidad1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocidad1EditField.Limits = [50 100];
            app.Velocidad1EditField.ValueDisplayFormat = '%.0f %%';
            app.Velocidad1EditField.Value = 100;
            app.ConfirmarMovimientoButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarMovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarMovimientoButtonPushed, true);
            app.ConfirmarMovimientoButton.Text = {'Confirmar'; 'Movimiento'};
            app.CopyrighLabel = uilabel(app.UIFigure);
            app.CopyrighLabel.Text = '© 2022 Universidad Politécnica de Madrid; Ricardo Saettone, Mario García, Javier Jiménez, Markus Kilpiäinen, Tomás Marín y Thanos Drossos';
            app.Velocidad2EditFieldLabel = uilabel(app.UIFigure);
            app.Velocidad2EditFieldLabel.Text = 'Velocidad 2 [%]';
            app.Velocidad2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocidad2EditField.Limits = [50 100];
            app.Velocidad2EditField.ValueDisplayFormat = '%.0f %%';
            app.Velocidad2EditField.Value = 100;
            app.Velocidad3EditFieldLabel = uilabel(app.UIFigure);
            app.Velocidad3EditFieldLabel.Text = 'Velocidad 3 [%]';
            app.Velocidad3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocidad3EditField.Limits = [10 100];
            app.Velocidad3EditField.ValueDisplayFormat = '%.0f %%';
            app.Velocidad3EditField.Value = 100;
            app.xmas = uibutton(app.UIFigure, 'push');
            app.xmas.ButtonPushedFcn = createCallbackFcn(app, @xmasButtonPushed, true);
            app.xmas.Text = '+';
            app.xmen = uibutton(app.UIFigure, 'push');
            app.xmen.ButtonPushedFcn = createCallbackFcn(app, @xmenButtonPushed, true);
            app.xmen.Text = '-';
            app.ymas = uibutton(app.UIFigure, 'push');
            app.ymas.ButtonPushedFcn = createCallbackFcn(app, @ymasButtonPushed, true);
            app.ymas.Text = '+';
            app.ymen = uibutton(app.UIFigure, 'push');
            app.ymen.ButtonPushedFcn = createCallbackFcn(app, @ymenButtonPushed, true);
            app.ymen.Text = '-';
            app.VelocidadconstanteButtonGroup = uibuttongroup(app.UIFigure);
            app.VelocidadconstanteButtonGroup.Title = 'Velocidad constante';
            app.ArticularButton = uiradiobutton(app.VelocidadconstanteButtonGroup);
            app.ArticularButton.Text = 'Articular';
            app.ArticularButton.Value = true;
            app.CartesianaButton = uiradiobutton(app.VelocidadconstanteButtonGroup);
            app.CartesianaButton.Text = 'Cartesiana';
            app.ListadeposicionesguardadasLabel = uilabel(app.UIFigure);
            app.ListadeposicionesguardadasLabel.Text = 'Lista de posiciones guardadas';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.Editable = 'off';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.PinzaEditFieldLabel = uilabel(app.UIFigure);
            app.PinzaEditFieldLabel.Text = 'Pinza';
            app.PinzaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaEditField.Editable = 'off';
            app.PosicionesdelrobotactualLabel = uilabel(app.UIFigure);
            app.PosicionesdelrobotactualLabel.Text = 'Posiciones del robot actual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grupo8_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end