classdef prueba1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EnviarDatos               matlab.ui.control.Button
        Sentido                   matlab.ui.control.Switch
        SentidoSwitchLabel        matlab.ui.control.Label
        Comunicacion              matlab.ui.control.Lamp
        ComunicacionLampLabel     matlab.ui.control.Label
        Desconectar               matlab.ui.control.Button
        Conectar                  matlab.ui.control.Button
        Angulo                    matlab.ui.control.NumericEditField
        AnguloEditFieldLabel      matlab.ui.control.Label
        Knob                      matlab.ui.control.Knob
        KnobLabel                 matlab.ui.control.Label
        SerialPort                matlab.ui.control.EditField
        SerialPortEditFieldLabel  matlab.ui.control.Label
        BaudRate                  matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        comu; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Comunicacion.Color = 'red';
            app.comu = serial(app.SerialPort.Value, 'Baudrate', app.BaudRate.Value, 'Terminator', 'CR/LF');
        end

        % Value changed function: BaudRate
        function BaudRateValueChanged(app, event)
            value = app.BaudRate.Value;
        end

        % Value changed function: SerialPort
        function SerialPortValueChanged(app, event)
            value = app.SerialPort.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.comu);
            delete(app.comu);
            delete(app);
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.Angulo.Value = round(changingValue);
        end

        % Button pushed function: Conectar
        function ConectarButtonPushed(app, event)
            fopen(app.comu); %Abro comunicacion
            app.Comunicacion.Color = 'green';
        end

        % Button pushed function: Desconectar
        function DesconectarButtonPushed(app, event)
            fprintf(app.comu, '0,0');
            fclose(app.comu);
            app.Comunicacion.Color = 'red';
            clear all;
        end

        % Button pushed function: EnviarDatos
        function EnviarDatosButtonPushed(app, event)
            char a;
            string msg;
            a = int2str(app.Angulo.Value);
            if strcmp(app.Sentido.Value, 'Izq')
                msg = ['0,',a];
                fprintf(app.comu, msg);

            elseif strcmp(app.Sentido.Value, 'Der')
                msg = ['1,',a];
                fprintf(app.comu, msg);

            end
        end

        % Value changed function: Angulo
        function AnguloValueChanged(app, event)
            value = app.Angulo.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @BaudRateValueChanged, true);
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPort = uieditfield(app.UIFigure, 'text');
            app.SerialPort.ValueChangedFcn = createCallbackFcn(app, @SerialPortValueChanged, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 360];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.Angulo = uieditfield(app.UIFigure, 'numeric');
            app.Angulo.ValueChangedFcn = createCallbackFcn(app, @AnguloValueChanged, true);
            app.Conectar = uibutton(app.UIFigure, 'push');
            app.Conectar.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.Conectar.Text = 'Conectar';
            app.Desconectar = uibutton(app.UIFigure, 'push');
            app.Desconectar.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.Desconectar.Text = 'Desconectar';
            app.ComunicacionLampLabel = uilabel(app.UIFigure);
            app.ComunicacionLampLabel.Text = 'Comunicacion';
            app.Comunicacion = uilamp(app.UIFigure);
            app.SentidoSwitchLabel = uilabel(app.UIFigure);
            app.SentidoSwitchLabel.Text = 'Sentido';
            app.Sentido = uiswitch(app.UIFigure, 'slider');
            app.Sentido.Items = {'Izq', 'Der'};
            app.Sentido.Value = 'Izq';
            app.EnviarDatos = uibutton(app.UIFigure, 'push');
            app.EnviarDatos.ButtonPushedFcn = createCallbackFcn(app, @EnviarDatosButtonPushed, true);
            app.EnviarDatos.Text = 'Enviar Datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end