classdef prueba2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CONTROLARDUINOCONMATLABLabel  matlab.ui.control.Label
        EnviarDatos                   matlab.ui.control.Button
        Sentido                       matlab.ui.control.Switch
        SentidoSwitchLabel            matlab.ui.control.Label
        Comunicacion                  matlab.ui.control.Lamp
        ComunicacionLampLabel         matlab.ui.control.Label
        Desconectar                   matlab.ui.control.Button
        Conectar                      matlab.ui.control.Button
        Angulo                        matlab.ui.control.NumericEditField
        AnguloEditFieldLabel          matlab.ui.control.Label
        Knob                          matlab.ui.control.Knob
        KnobLabel                     matlab.ui.control.Label
        SerialPort                    matlab.ui.control.EditField
        SerialPortEditFieldLabel      matlab.ui.control.Label
        BaudRate                      matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comu; % Description
        cntData = 0;
        nBuffer = 501;
        interrupted;

        buffer_timeStamp = NaN*ones(501,1);
        buffer_pulsos = NaN*ones(501,1);
        buffer_posi = NaN*ones(501,1);
        buffer_plot;
        buffer_plot_2;
    end
    
    methods (Access = private)
        
        function readArduinoSerialData(app)
            message = fscanf(app.comu, '%d, %d, %d');
            app.cntData = app.cntData + 1;

            if app.cntData < app.nBuffer
                app.buffer_timeStamp(app.cntData) = message(1)/1000;
                app.buffer_pulsos(app.cntData) = message(2);
                app.buffer_posi(app.cntData) = message(3);
            else
                app.buffer_timeStamp(end+1) = message(1)/1000;
                app.buffer_pulsos(end+1) = message(2);
                app.buffer_posi(end+1) = message(3);
            end
            
            app.buffer_plot = app.buffer_pulsos(end-500:end);
            app.buffer_plot_2 = app.buffer_posi(end-500:end);
            time_array = 0:0.01:5;

            plot(app.UIAxes, time_array, app.buffer_plot, time_array, app.buffer_plot_2)
            app.UIAxes.XLim = [0,5];
            app.UIAxes.YLim = [-1800,1800];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Comunicacion.Color = 'red';
        end

        % Value changed function: BaudRate
        function BaudRateValueChanged(app, event)
            value = app.BaudRate.Value;
        end

        % Value changed function: SerialPort
        function SerialPortValueChanged(app, event)
            value = app.SerialPort.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.comu);
                app.comu=[];
            catch
            end
            delete(app.comu);
            delete(app);
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.Angulo.Value = round(changingValue);
        end

        % Button pushed function: Conectar
        function ConectarButtonPushed(app, event)
            try
                fclose(app.comu);
                app.comu=[];
            catch
            end
            app.interrupted=0;
            app.comu = serial(app.SerialPort.Value, 'Baudrate', app.BaudRate.Value, 'Terminator', 'CR/LF');
            fopen(app.comu); %Abro comunicacion
            app.Comunicacion.Color = 'green';
            while app.interrupted == 0
                readArduinoSerialData(app);
            end
        end

        % Button pushed function: Desconectar
        function DesconectarButtonPushed(app, event)
            app.interrupted=1;
            fclose(app.comu);
            app.comu=[];
            app.Comunicacion.Color = 'red';
            clear all;
        end

        % Button pushed function: EnviarDatos
        function EnviarDatosButtonPushed(app, event)
            char a;
            string msg;
            a = int2str(app.Angulo.Value);
            if strcmp(app.Sentido.Value, 'Izq')
                msg = ['0,',a];
                fprintf(app.comu, msg);

            elseif strcmp(app.Sentido.Value, 'Der')
                msg = ['1,',a];
                fprintf(app.comu, msg);

            end
        end

        % Value changed function: Angulo
        function AnguloValueChanged(app, event)
            value = app.Angulo.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Posicion (pulsos)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [-1800 1800];
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @BaudRateValueChanged, true);
            app.BaudRate.Value = 9600;
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPort = uieditfield(app.UIFigure, 'text');
            app.SerialPort.ValueChangedFcn = createCallbackFcn(app, @SerialPortValueChanged, true);
            app.SerialPort.Value = 'COM13';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 360];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.Angulo = uieditfield(app.UIFigure, 'numeric');
            app.Angulo.ValueChangedFcn = createCallbackFcn(app, @AnguloValueChanged, true);
            app.Conectar = uibutton(app.UIFigure, 'push');
            app.Conectar.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.Conectar.Text = 'Conectar';
            app.Desconectar = uibutton(app.UIFigure, 'push');
            app.Desconectar.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.Desconectar.Text = 'Desconectar';
            app.ComunicacionLampLabel = uilabel(app.UIFigure);
            app.ComunicacionLampLabel.Text = 'Comunicacion';
            app.Comunicacion = uilamp(app.UIFigure);
            app.SentidoSwitchLabel = uilabel(app.UIFigure);
            app.SentidoSwitchLabel.Text = 'Sentido';
            app.Sentido = uiswitch(app.UIFigure, 'slider');
            app.Sentido.Items = {'Izq', 'Der'};
            app.Sentido.Value = 'Izq';
            app.EnviarDatos = uibutton(app.UIFigure, 'push');
            app.EnviarDatos.ButtonPushedFcn = createCallbackFcn(app, @EnviarDatosButtonPushed, true);
            app.EnviarDatos.Text = 'Enviar Datos';
            app.CONTROLARDUINOCONMATLABLabel = uilabel(app.UIFigure);
            app.CONTROLARDUINOCONMATLABLabel.Text = 'CONTROL ARDUINO CON MATLAB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end